classdef IVIS_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        IvisExperimentFolderEditFieldLabel  matlab.ui.control.Label
        IvisExperimentFolderEditField  matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        PlaceWellsButton               matlab.ui.control.Button
        WellDetectionLabel             matlab.ui.control.Label
        DetectionMethodButtonGroup     matlab.ui.container.ButtonGroup
        ManualButton                   matlab.ui.control.RadioButton
        AutomaticButton                matlab.ui.control.RadioButton
        UITable                        matlab.ui.control.Table
        PhotonCountAnalysisLabel       matlab.ui.control.Label
        AnalyzeWellsButton             matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ExperimentImageTab             matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        WellImageTab                   matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        GroupImageTab                  matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        WellCountsTab                  matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        AllWellsTab                    matlab.ui.container.Tab
        UITable2                       matlab.ui.control.Table
        Group1Tab                      matlab.ui.container.Tab
        UITable3                       matlab.ui.control.Table
        Tab                            matlab.ui.container.Tab
        TextAreaLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        experiment_directory    char % Description
        sub_directories         struct
        approximate_well_radii_range = [ 10 20 ] % pixels of well circle radii
        default_radii = 7
        image_scale = 5         % how much to enlarge the image for displaying figure
        num_rows = 8            % how many rows of wells
        num_cols = 12           % how many columns of wells
        sensitivity = 0.86      % for detecting circles, higher is more circles
        well_counts             double % num_well x num_reads, total light in well
        image_handle            matlab.graphics.primitive.Image
        num_readings            int32 % number of images taken in a given experiment
        photo_name_struct       struct % stores the names of the photos
        reporter_name_struct    struct % stores the names of the reporter photos
        photo_cell              cell % cell to store the photos once loaded
        reporter_cell           cell % cell to store the reporter photos
        photo_height            int32 % height of the photos
        photo_width             int32 % width of the photos
        display_photo = []      % photo to be displayed
        display_photo_rgb = []
        all_well_radii = []     % store the radii of all of the wells
        all_well_centers = []   % matrix to store the x, y positions of the well centers
        groups                  cell
        group_handle_cell       cell % store handles for markers {1}, and text {2}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.experiment_directory = uigetdir('Select Main Experiment Folder');
            app.IvisExperimentFolderEditField.Value = app.experiment_directory;
            app.TextArea.Value = [ 'Selected folder: ' app.experiment_directory ];
            
            % refocus window
            drawnow;
            figure( app.UIFigure );
            
            % find all of the file names for photos
            app.photo_name_struct = dir( strcat( app.experiment_directory, '/**/photograph.TIF' ) );
            app.reporter_name_struct = dir( strcat( app.experiment_directory, '/**/luminescent.TIF' ) );
            app.num_readings = size( app.photo_name_struct, 1 );
            
            % actually load in the photos
            [ app.photo_cell, app.reporter_cell ] = ...
                loadExperimentTiffs( app.photo_name_struct, app.reporter_name_struct, app.num_readings );
            app.photo_height = size( app.photo_cell{ 1 }, 1 );
            app.photo_width = size( app.photo_cell{ 1 }, 2 );
            
            app.display_photo = createDisplayPhoto( app.photo_cell, app.image_scale );
            app.display_photo_rgb = imresize( repmat( app.display_photo, 1, 1, 3 ), ...
                app.image_scale * size( app.display_photo ) );
            image( app.UIAxes, app.display_photo_rgb );
            image( app.UIAxes2, app.display_photo_rgb );
            image( app.UIAxes3, app.display_photo_rgb );
            
            empty_names = { 'Group 1'; 'Group 2'; 'Group 3'; 'Group 4'; 'Group 5'; 'Group 6' };
            empty_wells = { ''; ''; ''; ''; ''; '' };
            tdata = table( empty_names, empty_wells );
            app.UITable.Data = tdata;
            for ii = 1:length( empty_names )
                app.groups{ ii } = { empty_names{ ii }, [] };
            end
            
        end

        % Button pushed function: PlaceWellsButton
        function PlaceWellsButtonPushed(app, event)
            
            app.all_well_radii = ones( app.num_rows * app.num_cols, 1 ) * app.default_radii;
            
            if app.ManualButton.Value
                app.TextArea.Value = 'Manual';
                
                corner_fig = figure('Name', 'Select the four corner wells');
                imshow( app.display_photo );
                app.all_well_centers = manualSelection( app.num_rows, app.num_cols );
                close( corner_fig );
                
            elseif app.AutomaticButton.Value
                app.TextArea.Value = 'Automatic';
                
                [ app.all_well_centers, ~ ] = ...
                    automaticDetection( app.photo_cell, app.approximate_well_radii_range, app.sensitivity );
            end
            
            displayWells( app.UIAxes, app.all_well_centers, app.all_well_radii, app.image_scale )
            
            %{
            saveResults( app.well_counts, app.experiment_directory, app.image_handle );
                        %}
            
        end

        % Button pushed function: AnalyzeWellsButton
        function AnalyzeWellsButtonPushed(app, event)
            app.TextArea.Value = 'Analyzing wells...';
            app.well_counts = wellCountAnalysis( app.reporter_cell, app.all_well_centers, app.all_well_radii );
            app.TextArea.Value = [ app.TextArea.Value; newline 'Done analyzing wells.' ];
            
            size( app.well_counts )
            well_count_table = array2table( app.well_counts );
            app.UITable2.Data = well_count_table;

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            [ app.groups, msg ] = parseGroupData( indices, newData, app.groups );
            app.TextArea.Value = [ app.TextArea.Value; newline msg ];
            
            [ app.group_handle_cell, msg ] = displayGroupPhoto( app.UIAxes3, app.all_well_centers, ...
                app.all_well_radii, app.groups, app.image_scale, app.group_handle_cell );
            app.TextArea.Value = [ app.TextArea.Value; newline msg ];
            
            app.TabGroup.SelectedTab = app.GroupImageTab;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {598, 598};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {380, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IvisExperimentFolderEditFieldLabel = uilabel(app.LeftPanel);
            app.IvisExperimentFolderEditFieldLabel.Text = 'Ivis Experiment Folder';
            app.IvisExperimentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PlaceWellsButton = uibutton(app.LeftPanel, 'push');
            app.PlaceWellsButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceWellsButtonPushed, true);
            app.PlaceWellsButton.Text = 'Place Wells';
            app.WellDetectionLabel = uilabel(app.LeftPanel);
            app.WellDetectionLabel.Text = 'Well Detection';
            app.DetectionMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.DetectionMethodButtonGroup.Title = 'Detection Method';
            app.ManualButton = uiradiobutton(app.DetectionMethodButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticButton = uiradiobutton(app.DetectionMethodButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Group Name'; 'Column 2'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PhotonCountAnalysisLabel = uilabel(app.LeftPanel);
            app.PhotonCountAnalysisLabel.Text = 'Photon Count Analysis';
            app.AnalyzeWellsButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeWellsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeWellsButtonPushed, true);
            app.AnalyzeWellsButton.Text = 'Analyze Wells';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ExperimentImageTab = uitab(app.TabGroup);
            app.ExperimentImageTab.Title = 'Experiment Image';
            app.UIAxes2 = uiaxes(app.ExperimentImageTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.WellImageTab = uitab(app.TabGroup);
            app.WellImageTab.Title = 'Well Image';
            app.UIAxes = uiaxes(app.WellImageTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GroupImageTab = uitab(app.TabGroup);
            app.GroupImageTab.Title = 'Group Image';
            app.UIAxes3 = uiaxes(app.GroupImageTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.WellCountsTab = uitab(app.TabGroup);
            app.WellCountsTab.Title = 'Well Counts';
            app.TabGroup2 = uitabgroup(app.WellCountsTab);
            app.AllWellsTab = uitab(app.TabGroup2);
            app.AllWellsTab.Title = 'All Wells';
            app.UITable2 = uitable(app.AllWellsTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Group1Tab = uitab(app.TabGroup2);
            app.Group1Tab.Title = 'Group 1';
            app.UITable3 = uitable(app.Group1Tab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.TextAreaLabel = uilabel(app.RightPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IVIS_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end